classdef MiceListEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel2     matlab.ui.container.Panel
        DaysList2ET  matlab.ui.control.EditField
        DaysList1ET  matlab.ui.control.EditField
        SaveBT       matlab.ui.control.Button
        uipanel1     matlab.ui.container.Panel
        MiceList2ET  matlab.ui.control.EditField
        MiceList1ET  matlab.ui.control.EditField
    end

    properties (Access = private)
        ExperimentDirectory
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MiceListEditor_OpeningFcn(app, varargin)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Revision History
            %
            % 1.00 - Base release
            % 1.10 - Bug fixes
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if FileUtilities.VerifySWVersion('MiceListEditor', '1.10') == 1     % version error
                app.SaveBT.Enable = 'off'; 
            else            
                app.ExperimentDirectory = FileUtilities.LoadMouseGroupDirectory();
                if isempty(app.ExperimentDirectory)
                    app.SaveBT.Enable = 'off'; 
                else
                    if exist([app.ExperimentDirectory '\MiceList.mat'], 'file') == 2
                        load([app.ExperimentDirectory  '\MiceList'], 'MiceList1', 'MiceList2', 'DaysList1', 'DaysList2');
                        app.MiceList1ET.Value = strjoin(MiceList1, ' ');
                        app.MiceList2ET.Value = strjoin(MiceList2, ' ');
                        app.DaysList1ET.Value = strjoin(DaysList1, ' ');
                        app.DaysList2ET.Value = strjoin(DaysList2, ' ');
                    else
                        button = questdlg('Mice List file missing. Create?', 'Data Files');
                        if ~strcmp(button, 'Yes')
                            app.SaveBT.Enable = 'off'; 
                        end
                    end
                end
            end
        end

        % Button pushed function: SaveBT
        function SaveBT_Callback(app, event)
            delimiter = ' ';
            if ~isempty(strfind(app.MiceList1ET.Value, ','))
                delimiter = ',';
            end

            MiceList1 = strsplit(app.MiceList1ET.Value, delimiter);
            MiceList2 = strsplit(app.MiceList2ET.Value, delimiter);
            DaysList1 = strsplit(app.DaysList1ET.Value, delimiter);
            DaysList2 = strsplit(app.DaysList2ET.Value, delimiter);

            save([app.ExperimentDirectory '\MiceList'], 'MiceList1', 'MiceList2', 'DaysList1', 'DaysList2');
            app.figure1.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MiceListEditor';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Mice List';
            app.uipanel1.Tag = 'uipanel1';
            app.MiceList1ET = uieditfield(app.uipanel1, 'text');
            app.MiceList1ET.Tag = 'MiceList1ET';
            app.MiceList2ET = uieditfield(app.uipanel1, 'text');
            app.MiceList2ET.Tag = 'MiceList2ET';
            app.SaveBT = uibutton(app.figure1, 'push');
            app.SaveBT.ButtonPushedFcn = createCallbackFcn(app, @SaveBT_Callback, true);
            app.SaveBT.Tag = 'SaveBT';
            app.SaveBT.Text = 'Save';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Days List';
            app.uipanel2.Tag = 'uipanel2';
            app.DaysList1ET = uieditfield(app.uipanel2, 'text');
            app.DaysList1ET.Tag = 'DaysList1ET';
            app.DaysList2ET = uieditfield(app.uipanel2, 'text');
            app.DaysList2ET.Tag = 'DaysList2ET';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MiceListEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MiceListEditor_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end