classdef TasteAnalysisSW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TasteSystemUIFigure          matlab.ui.Figure
        ReportParameterPanel         matlab.ui.container.Panel
        SessionAlignmentPanel        matlab.ui.container.Panel
        AlignmentMethodButtonGroup   matlab.ui.container.ButtonGroup
        ConsumptionButton            matlab.ui.control.RadioButton
        TimeSecButton                matlab.ui.control.RadioButton
        EndEditField                 matlab.ui.control.NumericEditField
        EndEditFieldLabel            matlab.ui.control.Label
        StartEditField               matlab.ui.control.NumericEditField
        StartEditFieldLabel          matlab.ui.control.Label
        DataSetPanel                 matlab.ui.container.Panel
        ReportConfigurationPanel     matlab.ui.container.Panel
        NormalizeCheckBox            matlab.ui.control.CheckBox
        BinsEditField                matlab.ui.control.NumericEditField
        BinsEditFieldLabel           matlab.ui.control.Label
        yLimEditField                matlab.ui.control.NumericEditField
        yLimEditFieldLabel           matlab.ui.control.Label
        CompositeOutputCheckBox      matlab.ui.control.CheckBox
        ReportSelectionPanel         matlab.ui.container.Panel
        RunReportB                   matlab.ui.control.Button
        OutputFileTagEditField       matlab.ui.control.EditField
        OutputFileTagEditFieldLabel  matlab.ui.control.Label
        ReportDropDown               matlab.ui.control.DropDown
        CategoryDropDown             matlab.ui.control.DropDown
        ExperimeParametersPanel      matlab.ui.container.Panel
        MiceListB                    matlab.ui.control.Button
        DayEditField                 matlab.ui.control.EditField
        DayEditFieldLabel            matlab.ui.control.Label
        MouseEditField               matlab.ui.control.EditField
        MouseEditFieldLabel          matlab.ui.control.Label
        MouseGroupB                  matlab.ui.control.Button
        SessionEditField             matlab.ui.control.EditField
        SessionEditFieldLabel        matlab.ui.control.Label
        SessionNameB                 matlab.ui.control.Button
        MouseGroupEditField          matlab.ui.control.EditField
        ExpNameLabel                 matlab.ui.control.Label
        ProjectParametersPanel       matlab.ui.container.Panel
        ValidBurstILImSecPanel       matlab.ui.container.Panel
        MinILIinBurstLabel           matlab.ui.control.Label
        MaxBurstILILabel             matlab.ui.control.Label
        MaxPrimaryILILabel           matlab.ui.control.Label
        MinILIInBurst                matlab.ui.control.NumericEditField
        MaxBurstILI                  matlab.ui.control.NumericEditField
        MaxPrimaryILI                matlab.ui.control.NumericEditField
        ValidILIRangePanel           matlab.ui.container.Panel
        MaxmSecLabel                 matlab.ui.control.Label
        MinmSecLabel                 matlab.ui.control.Label
        MaxILIRange                  matlab.ui.control.NumericEditField
        MinILIRange                  matlab.ui.control.NumericEditField
        ProjectDirectoryB            matlab.ui.control.Button
        ProjectDirectoryEditField    matlab.ui.control.EditField
        ProjectDirectoryLabel        matlab.ui.control.Label
        TasteSystemAnalysisSWLauncherLabel  matlab.ui.control.Label
    end

   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   %
   % Revision History:
   % 1.00 = First release
   % 1.10 = Finishing touches to first release
   % 1.20 = Export Burst analysis added. Time alignment and analysis bins added
   % 1.30 = Export Behavioral data added.
   % 1.40 = Max ILI characterization added.
   %
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
         ProjectDirectory
         ProjectData
    end
    
    methods (Access = private)

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Initializes default values of window fields based on system global
        % configuration file from the project directory. 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function initWindow(app)
            app.ProjectDirectoryEditField.Value = app.ProjectDirectory;

            app.MinILIRange.Value = app.ProjectData.MinLickTime;
            app.MaxILIRange.Value = app.ProjectData.MaxLickTime;
            app.MaxPrimaryILI.Value = app.ProjectData.MaxPrimaryILI;
            app.MaxBurstILI.Value = app.ProjectData.MaxBurstILI; 
            app.MinILIInBurst.Value = app.ProjectData.MinILIPerBurst;
            app.StartEditField.Value = app.ProjectData.StartTime;
            app.EndEditField.Value = app.ProjectData.EndTime;
            app.MouseGroupEditField.Value = app.ProjectData.MouseGroupName;
            app.SessionEditField.Value = app.ProjectData.SessionName;
            app.BinsEditField.Value = app.ProjectData.BinNum;
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Saves project system data to configuration files.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function SaveProjectData(app)
            app.ProjectData.MinLickTime = app.MinILIRange.Value;
            app.ProjectData.MaxLickTime = app.MaxILIRange.Value; 
            app.ProjectData.MaxPrimaryILI = app.MaxPrimaryILI.Value;
            app.ProjectData.MaxBurstILI = app.MaxBurstILI.Value;
            app.ProjectData.MinILIPerBurst = app.MinILIInBurst.Value;
            app.ProjectData.StartTime = app.StartEditField.Value;
            app.ProjectData.EndTime = app.EndEditField.Value;
            app.ProjectData.BinNum = app.BinsEditField.Value;

            if ~strcmp(app.SessionEditField.Value, 'all')
                app.ProjectData.SessionName = app.SessionEditField.Value;
            end
            if ~strcmp(app.MouseGroupEditField.Value, 'all')
                app.ProjectData.MouseGroupName = app.MouseGroupEditField.Value;
            end  

            FileUtilities.SaveProjectData(app.ProjectDirectory, app.ProjectData)   % Save project data file

            app.ProjectData.SessionName = app.SessionEditField.Value;  % Store wild-card names in structure but not in file
            app.ProjectData.MouseGroupName = app.MouseGroupEditField.Value;
        end
        

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Calls the appropritaes report generator based on the launcher's
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function RunReport(app)
    
            % convert radio button and check box selections to a single struct
            %ReportParameters = struct ('CompositOutput', app.CompositeOutputCheckBox.Value);
                    
            if strcmp(app.OutputFileTagEditField.Value, 'No Tag')
                OutputFileTag = [];
            else
                OutputFileTag = ['_' app.OutputFileTagEditField.Value];
            end

            if app.ConsumptionButton.Value == 1
                binType = 1;
            else
                binType = 0;
            end

            switch (app.ReportDropDown.Value)
                case 'Licking'
                    ActivityRasters.LickingRasters(app.ProjectDirectory, app.ProjectData, app.MouseEditField.Value, ...
                                   app.DayEditField.Value, OutputFileTag);

                case 'Lick Bursts'   
                    ActivityRasters.BurstRasters(app.ProjectDirectory, app.ProjectData, app.MouseEditField.Value, ... 
                                   app.DayEditField.Value, OutputFileTag);

                case 'Burst Analysis'
                    ExportData.ExportBursts(app.ProjectDirectory, app.ProjectData, app.MouseEditField.Value, ...
                                   app.DayEditField.Value, binType, OutputFileTag);
                case 'Behavioral Data' 
                    ExportData.ExportBehavior(app.ProjectDirectory, app.ProjectData, app.MouseEditField.Value, ...
                                   app.DayEditField.Value);
                    
                case 'ILI Distribuion'
                    DataCharReports.ILIDistribution(app.ProjectDirectory, app.ProjectData, app.MouseEditField.Value, ...
                                               app.DayEditField.Value, app.NormalizeCheckBox.Value);
             
                case 'Max Burst ILI'
                    DataCharReports.MaxBurstILI(app.ProjectDirectory, app.ProjectData, app.MouseEditField.Value, ...
                                               app.DayEditField.Value, app.NormalizeCheckBox.Value);
                    
                otherwise    
                    msgbox('Sorry guys, this report is not implemented, yet. Coming out soon.');                 
            end
        end
    end


%                 case 4
%                     DataCharReports(handles.ReportMenu.Value - 1, GraphParameters, handles.MouseNum.String, handles.DayNum.String);
%                     
%                 case 5
%                     ExportData(handles.ReportMenu.Value - 1, GraphParameters, handles.MouseNum.String, handles.DayNum.String);
%         
%                 case 6
%                     Statistics(handles.ReportMenu.Value - 1, GraphParameters, handles.MouseNum.String, handles.DayNum.String);


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if FileUtilities.VerifySWVersion('TasteAnalysisSW', '1.40') == 1     % version error
                app.RunReportB.Enable = 'off'; 
            end

            app.ProjectDirectory = FileUtilities.LoadProjectDirectory();

            if isempty(app.ProjectDirectory)                % Project Directory Warning
                app.RunReportB.Enable = 'off';
            else
                app.ProjectDirectoryEditField.Value = app.ProjectDirectory;  
                app.ProjectData = FileUtilities.LoadProjectData(app.ProjectDirectory);
                if isempty(app.ProjectData)
                    app.RunReportB.Enable = 'off';
                else
                    app.RunReportB.Enable = 'on';                    
                    app.initWindow();
                end
            end
        end

        % Button pushed function: ProjectDirectoryB
        function ProjectDirectoryBButtonPushed(app, event)
            app.ProjectDirectory = FileUtilities.SelectProjectDirectory();

            if isempty(app.ProjectDirectory)
                app.RunReportB.Enable = 'off';
            else
                app.ProjectDirectoryEditField.Value = app.ProjectDirectory;
                app.ProjectData = FileUtilities.LoadProjectData(app.ProjectDirectory);
                if isempty(app.ProjectData)
                    app.RunReportB.Enable = 'off';
                else
                    app.RunReportB.Enable = 'on';                    
                    app.initWindow();
                end
            end
        end

        % Value changed function: CategoryDropDown
        function CategoryDropDownValueChanged(app, event)
            app.ReportDropDown.Value = {'Select Report'};
            app.ReportDropDown.Enable = 'off';

            switch app.CategoryDropDown.Value
                case 'Activity Rasters'
                    app.ReportDropDown.Items = {'Select Report', 'Licking', 'Lick Bursts', 'Neuronal', ...
                                                  'Neuron PSTH'};
                    app.ReportDropDown.Enable = 'on'; 
                    
                case 'Data Characterization' 
                    app.ReportDropDown.Items = {'Select Report', 'ILI Distribuion', 'Max Burst ILI', 'Water Consumption', 'Burst Length Distribution', ...
                                                  'Burst efficiency matrix'};
                    app.ReportDropDown.Enable = 'on'; 
                    
                case 'Export Behavioral Data'  
                    app.ReportDropDown.Items = {'Select Report', 'Burst Analysis', 'Behavioral Data' 'Accumulated bins', 'All Data', 'ILI Distribution'};
                    app.ReportDropDown.Enable = 'on'; 
            end
        end

        % Button pushed function: MouseGroupB
        function MouseGroupBButtonPushed(app, event)
            ExperimentDirectory = uigetdir(app.ProjectDirectory);
            BackSlashs = find(ExperimentDirectory == '\');
            app.MouseGroupEditField.Value = extractAfter(ExperimentDirectory, BackSlashs(end));
        end

        % Button pushed function: SessionNameB
        function SessionNameBButtonPushed(app, event)
            SessionName = FileUtilities.SelectSessionName(app.ProjectDirectory, app.MouseGroupEditField.Value);
            if ~isempty(SessionName)      
                app.SessionEditField.Value = SessionName;
            end
        end

        % Button pushed function: MiceListB
        function MiceListBButtonPushed(app, event)
            app.SaveProjectData();
            MiceListEditor();
        end

        % Button pushed function: RunReportB
        function RunReportBButtonPushed(app, event)
            DataIsValid = true;  % flags all data on acreen is Ok and we are clear to run the requested report

            % Two fields are validated: Project directory and selected report. 
            if isempty(app.ProjectDirectoryEditField.Value)
                msgbox('Project directory is udefined');
                DataIsValid = false;
            end 
            
            if strcmp(app.ReportDropDown.Value, 'Select Report')  % no report selected
                msgbox('Please Selected an analysis report');
                DataIsValid = false;
            end

            % Some option combinations are not supported. 
            if strcmp(app.MouseEditField.Value, 'list') && strcmp(app.DayEditField.Value, 'list')
                msgbox('Mouse and Day cannot be both set to list. Please reedit');
                DataIsValid = false;
            end

            if app.EndEditField.Value <= app.StartEditField.Value
                msgbox('Session End time must be greater than Session Start time. Please reedit');
                DataIsValid = false;
            end
            
            if DataIsValid
                app.SaveProjectData();   
                app.RunReport();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TasteSystemUIFigure = uifigure('Visible', 'off');
            app.TasteSystemUIFigure.Name = 'Taste System';
            app.TasteSystemAnalysisSWLauncherLabel = uilabel(app.TasteSystemUIFigure);
            app.TasteSystemAnalysisSWLauncherLabel.Text = 'Taste System - Analysis SW Launcher';
            app.ProjectParametersPanel = uipanel(app.TasteSystemUIFigure);
            app.ProjectParametersPanel.Title = 'Project Parameters';
            app.ProjectDirectoryLabel = uilabel(app.ProjectParametersPanel);
            app.ProjectDirectoryLabel.Text = 'Project Directory:';
            app.ProjectDirectoryEditField = uieditfield(app.ProjectParametersPanel, 'text');
            app.ProjectDirectoryB = uibutton(app.ProjectParametersPanel, 'push');
            app.ProjectDirectoryB.ButtonPushedFcn = createCallbackFcn(app, @ProjectDirectoryBButtonPushed, true);
            app.ProjectDirectoryB.Tag = 'SelectProjectDirectory';
            app.ProjectDirectoryB.Text = 'Browse';
            app.ValidILIRangePanel = uipanel(app.ProjectParametersPanel);
            app.ValidILIRangePanel.Title = 'Valid ILI Range';
            app.MinILIRange = uieditfield(app.ValidILIRangePanel, 'numeric');
            app.MinILIRange.Value = 50;
            app.MaxILIRange = uieditfield(app.ValidILIRangePanel, 'numeric');
            app.MinmSecLabel = uilabel(app.ValidILIRangePanel);
            app.MinmSecLabel.Text = 'Min [mSec]';
            app.MaxmSecLabel = uilabel(app.ValidILIRangePanel);
            app.MaxmSecLabel.Text = 'Max [mSec]';
            app.ValidBurstILImSecPanel = uipanel(app.ProjectParametersPanel);
            app.ValidBurstILImSecPanel.Title = 'Valid Burst ILI [mSec]';
            app.MaxPrimaryILI = uieditfield(app.ValidBurstILImSecPanel, 'numeric');
            app.MaxPrimaryILI.Value = 180;
            app.MaxBurstILI = uieditfield(app.ValidBurstILImSecPanel, 'numeric');
            app.MaxBurstILI.Value = 300;
            app.MinILIInBurst = uieditfield(app.ValidBurstILImSecPanel, 'numeric');
            app.MinILIInBurst.Value = 2;
            app.MaxPrimaryILILabel = uilabel(app.ValidBurstILImSecPanel);
            app.MaxPrimaryILILabel.Text = 'Max Primary ILI';
            app.MaxBurstILILabel = uilabel(app.ValidBurstILImSecPanel);
            app.MaxBurstILILabel.Text = 'Max Burst ILI';
            app.MinILIinBurstLabel = uilabel(app.ValidBurstILImSecPanel);
            app.MinILIinBurstLabel.Text = 'Min # ILI in Burst';
            app.ExperimeParametersPanel = uipanel(app.TasteSystemUIFigure);
            app.ExperimeParametersPanel.Title = 'Experiment Parameters';
            app.ExpNameLabel = uilabel(app.ExperimeParametersPanel);
            app.ExpNameLabel.Text = 'Mouse Group:';
            app.MouseGroupEditField = uieditfield(app.ExperimeParametersPanel, 'text');
            app.SessionNameB = uibutton(app.ExperimeParametersPanel, 'push');
            app.SessionNameB.ButtonPushedFcn = createCallbackFcn(app, @SessionNameBButtonPushed, true);
            app.SessionNameB.Text = 'Browse';
            app.SessionEditFieldLabel = uilabel(app.ExperimeParametersPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.ExperimeParametersPanel, 'text');
            app.MouseGroupB = uibutton(app.ExperimeParametersPanel, 'push');
            app.MouseGroupB.ButtonPushedFcn = createCallbackFcn(app, @MouseGroupBButtonPushed, true);
            app.MouseGroupB.Text = 'Browse';
            app.MouseEditFieldLabel = uilabel(app.ExperimeParametersPanel);
            app.MouseEditFieldLabel.Text = 'Mouse #:';
            app.MouseEditField = uieditfield(app.ExperimeParametersPanel, 'text');
            app.DayEditFieldLabel = uilabel(app.ExperimeParametersPanel);
            app.DayEditFieldLabel.Text = 'Day #:';
            app.DayEditField = uieditfield(app.ExperimeParametersPanel, 'text');
            app.MiceListB = uibutton(app.ExperimeParametersPanel, 'push');
            app.MiceListB.ButtonPushedFcn = createCallbackFcn(app, @MiceListBButtonPushed, true);
            app.MiceListB.Text = 'Mice List';
            app.ReportParameterPanel = uipanel(app.TasteSystemUIFigure);
            app.ReportParameterPanel.Title = 'Report Parameter';
            app.ReportSelectionPanel = uipanel(app.ReportParameterPanel);
            app.ReportSelectionPanel.Title = 'Report Selection';
            app.CategoryDropDown = uidropdown(app.ReportSelectionPanel);
            app.CategoryDropDown.Items = {'Select Category', 'Activity Rasters', 'Data Characterization', 'Export Behavioral Data'};
            app.CategoryDropDown.ValueChangedFcn = createCallbackFcn(app, @CategoryDropDownValueChanged, true);
            app.CategoryDropDown.Value = 'Select Category';
            app.ReportDropDown = uidropdown(app.ReportSelectionPanel);
            app.ReportDropDown.Items = {'Select Report'};
            app.ReportDropDown.Enable = 'off';
            app.ReportDropDown.Value = 'Select Report';
            app.OutputFileTagEditFieldLabel = uilabel(app.ReportSelectionPanel);
            app.OutputFileTagEditFieldLabel.Text = 'Output File Tag';
            app.OutputFileTagEditField = uieditfield(app.ReportSelectionPanel, 'text');
            app.OutputFileTagEditField.Value = 'No Tag';
            app.RunReportB = uibutton(app.ReportSelectionPanel, 'push');
            app.RunReportB.ButtonPushedFcn = createCallbackFcn(app, @RunReportBButtonPushed, true);
            app.RunReportB.Text = 'Run Report';
            app.ReportConfigurationPanel = uipanel(app.ReportParameterPanel);
            app.ReportConfigurationPanel.Title = 'Report Configuration';
            app.CompositeOutputCheckBox = uicheckbox(app.ReportConfigurationPanel);
            app.CompositeOutputCheckBox.Text = 'Composite Output';
            app.yLimEditFieldLabel = uilabel(app.ReportConfigurationPanel);
            app.yLimEditFieldLabel.Text = 'yLim';
            app.yLimEditField = uieditfield(app.ReportConfigurationPanel, 'numeric');
            app.BinsEditFieldLabel = uilabel(app.ReportConfigurationPanel);
            app.BinsEditFieldLabel.Text = 'Bins';
            app.BinsEditField = uieditfield(app.ReportConfigurationPanel, 'numeric');
            app.BinsEditField.Value = 1;
            app.NormalizeCheckBox = uicheckbox(app.ReportConfigurationPanel);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.DataSetPanel = uipanel(app.ReportParameterPanel);
            app.DataSetPanel.Title = 'Data Set';
            app.SessionAlignmentPanel = uipanel(app.ReportParameterPanel);
            app.SessionAlignmentPanel.Title = 'Session Alignment';
            app.StartEditFieldLabel = uilabel(app.SessionAlignmentPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.SessionAlignmentPanel, 'numeric');
            app.EndEditFieldLabel = uilabel(app.SessionAlignmentPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.SessionAlignmentPanel, 'numeric');
            app.EndEditField.Value = 1200;
            app.AlignmentMethodButtonGroup = uibuttongroup(app.SessionAlignmentPanel);
            app.AlignmentMethodButtonGroup.Title = 'Alignment Method';
            app.TimeSecButton = uiradiobutton(app.AlignmentMethodButtonGroup);
            app.TimeSecButton.Text = 'Time [Sec]';
            app.TimeSecButton.Value = true;
            app.ConsumptionButton = uiradiobutton(app.AlignmentMethodButtonGroup);
            app.ConsumptionButton.Text = 'Consumption [%]';
            % Show the figure after all components are created
            app.TasteSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TasteAnalysisSW

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TasteSystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TasteSystemUIFigure)
        end
    end
end