classdef TasteExtractData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TasteSystemUIFigure             matlab.ui.Figure
        DataSetPanel                    matlab.ui.container.Panel
        Lickometer2CheckBox             matlab.ui.control.CheckBox
        Lickometer1CheckBox             matlab.ui.control.CheckBox
        NeuronalCheckBox                matlab.ui.control.CheckBox
        BehavioralCheckBox              matlab.ui.control.CheckBox
        ExtractButton                   matlab.ui.control.Button
        DataSourceButtonGroup           matlab.ui.container.ButtonGroup
        TDTPORTButtonGroup              matlab.ui.container.ButtonGroup
        PortCButton                     matlab.ui.control.RadioButton
        PortAButton                     matlab.ui.control.RadioButton
        TDTButton                       matlab.ui.control.RadioButton
        DavisButton                     matlab.ui.control.RadioButton
        IntanButton                     matlab.ui.control.RadioButton
        SessionParametersPanel          matlab.ui.container.Panel
        ExtractDataFromFolderEditField  matlab.ui.control.EditField
        ExtractDataFromFolderEditFieldLabel  matlab.ui.control.Label
        DayEditField                    matlab.ui.control.EditField
        DayEditFieldLabel               matlab.ui.control.Label
        MouseEditField                  matlab.ui.control.EditField
        MouseEditFieldLabel             matlab.ui.control.Label
        SessionEditField                matlab.ui.control.EditField
        SessionEditFieldLabel           matlab.ui.control.Label
        ExtractFromB                    matlab.ui.control.Button
        MouseGroupEditField             matlab.ui.control.EditField
        ExpNameLabel                    matlab.ui.control.Label
        ProjectParametersPanel          matlab.ui.container.Panel
        ProjectDirectoryB               matlab.ui.control.Button
        ProjectDirectoryEditField       matlab.ui.control.EditField
        ProjectDirectoryLabel           matlab.ui.control.Label
        TasteSystemDataExtractionLabel  matlab.ui.control.Label
    end

   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   %
   % Revision History:
   % 1.00 - First release
   % 1.10 - Added support for Neuronal data extraction (TDT only). Added support for saving system 
   %        configuration data in Project system file, improved selection of recorded data directory 
   % 1.20 - Added Support for selecting file for extraction through browsing 
   %
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
         ProjectDirectory
         ProjectData
    end
    
    methods (Access = private)

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Initializes default values of window fields based on system global
        % configuration file from the project directory. 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function initWindow(app)
            app.ProjectDirectoryEditField.Value = app.ProjectDirectory;
            app.MouseGroupEditField.Value = app.ProjectData.MouseGroupName;
            app.SessionEditField.Value = app.ProjectData.SessionName;
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Saves project system data to configuration files.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function SaveProjectData(app)

            if ~strcmp(app.SessionEditField.Value, 'all')
                app.ProjectData.SessionName = app.SessionEditField.Value;
            end
            if ~strcmp(app.MouseGroupEditField.Value, 'all')
                app.ProjectData.MouseGroupName = app.MouseGroupEditField.Value;
            end  

            FileUtilities.SaveProjectData(app.ProjectDirectory, app.ProjectData)   % Save project data file

            app.ProjectData.SessionName = app.SessionEditField.Value;  % Store wild-card names in structure but not in file
            app.ProjectData.MouseGroupName = app.MouseGroupEditField.Value;
        end
    end        


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if FileUtilities.VerifySWVersion('TasteExtractData', '1.20') == 1     % version error
                app.ExtractButton.Enable = 'off';
            else    
                app.ProjectDirectory = FileUtilities.LoadProjectDirectory();    
                if isempty(app.ProjectDirectory)                % Project Directory Warning
                    app.ExtractButton.Enable = 'off';
                else
                    app.ProjectDirectoryEditField.Value = app.ProjectDirectory;  
                    app.ProjectData = FileUtilities.LoadProjectData(app.ProjectDirectory);
                    if isempty(app.ProjectData)
                        app.ExtractFromB.Enable = 'off';
                    else
                        app.ExtractFromB.Enable = 'on';                    
                        app.initWindow();
                    end
                end
            end
        end

        % Button pushed function: ProjectDirectoryB
        function ProjectDirectoryBButtonPushed(app, event)
            app.ProjectDirectory = FileUtilities.SelectProjectDirectory();

            if isempty(app.ProjectDirectory)
                app.ExtractButton.Enable = 'off';
            else
                app.ProjectDirectoryEditField.Value = app.ProjectDirectory;
                app.ProjectData = FileUtilities.LoadProjectData(app.ProjectDirectory);
                if isempty(app.ProjectData)
                    app.ExtractButton.Enable = 'off';
                else
                    app.ExtractButton.Enable = 'on';                    
                    app.initWindow();
                end
            end
        end

        % Callback function
        function MouseGroupBButtonButtonPushed(app, event)
            
        end

        % Button pushed function: ExtractFromB
        function ExtractFromBButtonPushed(app, event)
            ExtractFrom = uigetdir(app.ProjectDirectoryEditField.Value);
            if ExtractFrom ~= 0
                app.ExtractDataFromFolderEditField.Value = ExtractFrom;
                mouseData = FileUtilities.ExtractMouseID(app.ProjectDirectoryEditField.Value, ExtractFrom, ExtractFrom);
                if ~isempty(mouseData.Group)
                    app.MouseGroupEditField.Value = mouseData.Group;
                    app.SessionEditField.Value = mouseData.Session;
                    app.MouseEditField.Value = mouseData.Mouse;
                    app.DayEditField.Value = mouseData.Day;
                    app.ExtractButton.Enable = 1;
                end
            end
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            if app.IntanButton.Value == 1
                FileExtention = 'rhd';
            elseif app.DavisButton.Value == 1
                FileExtention = 'xlsx';
            elseif app.TDTButton.Value == 1
                FileExtention = 'tsq';
            end

            if app.BehavioralCheckBox.Value == 1
                SaveProjectData(app);
                OutFileDirectory = FileUtilities.LocateOutfileDir(app.ProjectData, app.MouseEditField.Value, 'Licking Data');
                if FileUtilities.CheckDir(app.ProjectDirectory, OutFileDirectory) == 0
                    backslashes = strfind(app.ExtractDataFromFolderEditField.Value, '\');
                    DirName = extractAfter(app.ExtractDataFromFolderEditField.Value, backslashes(end));
                    DateCode = extractBefore(DirName, ['_' app.SessionEditField.Value]); 
    
                    if app.BehavioralCheckBox.Value == 1
                        OutFilePrefix = [app.ProjectDirectory, '\', OutFileDirectory, '\', DateCode, '_' app.SessionEditField.Value, '_Day', app.DayEditField.Value];
                        if app.IntanButton.Value == 1
                            ExtractBehavioralData.IntanData(app.ExtractDataFromFolderEditField.Value, OutFilePrefix);
                        elseif app.DavisButton.Value == 1
                            ExtractBehavioralData.DavisData(app.ExtractDataFromFolderEditField.Value, OutFilePrefix);
                        elseif app.TDTButton.Value == 1
                            ExtractBehavioralData.TDTData(app.ExtractDataFromFolderEditField.Value, OutFilePrefix);
                        end
                    end
                end
            end

            if app.NeuronalCheckBox.Value == 1
                ExtractNeuronalData(FileList, OutFilePrefix);
            end
        end

        % Selection changed function: DataSourceButtonGroup
        function DataSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataSourceButtonGroup.SelectedObject;
            if selectedButton.Text == "TDT"
                app.TDTPORTButtonGroup.Enable = "on";
            else
                app.TDTPORTButtonGroup.Enable = "off";
            end
        end

        % Value changed function: BehavioralCheckBox
        function BehavioralCheckBoxValueChanged(app, event)
            value = app.BehavioralCheckBox.Value;
            if value == 1
                app.Lickometer1CheckBox.Enable = 'on';
                app.Lickometer2CheckBox.Enable = 'on';
            else
                app.Lickometer1CheckBox.Enable = 'off';
                app.Lickometer2CheckBox.Enable = 'off';
            end                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TasteSystemUIFigure = uifigure('Visible', 'off');
            app.TasteSystemUIFigure.Name = 'Taste System';
            app.TasteSystemDataExtractionLabel = uilabel(app.TasteSystemUIFigure);
            app.TasteSystemDataExtractionLabel.Text = 'Taste System - Data Extraction';
            app.ProjectParametersPanel = uipanel(app.TasteSystemUIFigure);
            app.ProjectParametersPanel.Title = 'Project Parameters';
            app.ProjectDirectoryLabel = uilabel(app.ProjectParametersPanel);
            app.ProjectDirectoryLabel.Text = 'Project Directory:';
            app.ProjectDirectoryEditField = uieditfield(app.ProjectParametersPanel, 'text');
            app.ProjectDirectoryB = uibutton(app.ProjectParametersPanel, 'push');
            app.ProjectDirectoryB.ButtonPushedFcn = createCallbackFcn(app, @ProjectDirectoryBButtonPushed, true);
            app.ProjectDirectoryB.Tag = 'SelectProjectDirectory';
            app.ProjectDirectoryB.Text = 'Browse';
            app.SessionParametersPanel = uipanel(app.TasteSystemUIFigure);
            app.SessionParametersPanel.Title = 'Session Parameters';
            app.ExpNameLabel = uilabel(app.SessionParametersPanel);
            app.ExpNameLabel.Text = 'Mouse Group:';
            app.MouseGroupEditField = uieditfield(app.SessionParametersPanel, 'text');
            app.MouseGroupEditField.Editable = 'off';
            app.ExtractFromB = uibutton(app.SessionParametersPanel, 'push');
            app.ExtractFromB.ButtonPushedFcn = createCallbackFcn(app, @ExtractFromBButtonPushed, true);
            app.ExtractFromB.Text = 'Browse';
            app.SessionEditFieldLabel = uilabel(app.SessionParametersPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.SessionParametersPanel, 'text');
            app.SessionEditField.Editable = 'off';
            app.MouseEditFieldLabel = uilabel(app.SessionParametersPanel);
            app.MouseEditFieldLabel.Text = 'Mouse #:';
            app.MouseEditField = uieditfield(app.SessionParametersPanel, 'text');
            app.MouseEditField.Editable = 'off';
            app.DayEditFieldLabel = uilabel(app.SessionParametersPanel);
            app.DayEditFieldLabel.Text = 'Day #:';
            app.DayEditField = uieditfield(app.SessionParametersPanel, 'text');
            app.DayEditField.Editable = 'off';
            app.ExtractDataFromFolderEditFieldLabel = uilabel(app.SessionParametersPanel);
            app.ExtractDataFromFolderEditFieldLabel.Text = 'Extract Data From (Folder):';
            app.ExtractDataFromFolderEditField = uieditfield(app.SessionParametersPanel, 'text');
            app.DataSourceButtonGroup = uibuttongroup(app.TasteSystemUIFigure);
            app.DataSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSourceButtonGroupSelectionChanged, true);
            app.DataSourceButtonGroup.Title = 'Data Source';
            app.IntanButton = uiradiobutton(app.DataSourceButtonGroup);
            app.IntanButton.Text = 'Intan';
            app.DavisButton = uiradiobutton(app.DataSourceButtonGroup);
            app.DavisButton.Text = 'Davis';
            app.TDTButton = uiradiobutton(app.DataSourceButtonGroup);
            app.TDTButton.Text = 'TDT';
            app.TDTButton.Value = true;
            app.TDTPORTButtonGroup = uibuttongroup(app.DataSourceButtonGroup);
            app.TDTPORTButtonGroup.Title = 'TDT PORT';
            app.PortAButton = uiradiobutton(app.TDTPORTButtonGroup);
            app.PortAButton.Text = 'Port A';
            app.PortCButton = uiradiobutton(app.TDTPORTButtonGroup);
            app.PortCButton.Text = 'Port C';
            app.PortCButton.Value = true;
            app.ExtractButton = uibutton(app.TasteSystemUIFigure, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Enable = 'off';
            app.ExtractButton.Text = 'Extract';
            app.DataSetPanel = uipanel(app.TasteSystemUIFigure);
            app.DataSetPanel.Title = 'Data Set';
            app.BehavioralCheckBox = uicheckbox(app.DataSetPanel);
            app.BehavioralCheckBox.ValueChangedFcn = createCallbackFcn(app, @BehavioralCheckBoxValueChanged, true);
            app.BehavioralCheckBox.Text = 'Behavioral';
            app.BehavioralCheckBox.Value = true;
            app.NeuronalCheckBox = uicheckbox(app.DataSetPanel);
            app.NeuronalCheckBox.Enable = 'off';
            app.NeuronalCheckBox.Text = 'Neuronal';
            app.Lickometer1CheckBox = uicheckbox(app.DataSetPanel);
            app.Lickometer1CheckBox.Text = 'Lickometer 1';
            app.Lickometer1CheckBox.Value = true;
            app.Lickometer2CheckBox = uicheckbox(app.DataSetPanel);
            app.Lickometer2CheckBox.Text = 'Lickometer 2';
            % Show the figure after all components are created
            app.TasteSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TasteExtractData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TasteSystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TasteSystemUIFigure)
        end
    end
end